classdef Ball_beam_V4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        InputSetpoint                matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        SimulateButton2              matlab.ui.control.Button
        KdField                      matlab.ui.control.NumericEditField
        KDLabel                      matlab.ui.control.Label
        KiField                      matlab.ui.control.NumericEditField
        KILabel                      matlab.ui.control.Label
        KpField                      matlab.ui.control.NumericEditField
        KPEditFieldLabel             matlab.ui.control.Label
        SimulateButton               matlab.ui.control.Button
        InputPosition                matlab.ui.control.NumericEditField
        InputPositionEditFieldLabel  matlab.ui.control.Label
        UIAxesSim                    matlab.ui.control.UIAxes
        InputAxes                    matlab.ui.control.UIAxes
        AngleAxes                    matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    methods (Access = private)
        
        % startupFcn to draw the static 2D ball and beam system
        function startupFcn(app)
            % Parámetros visuales
            L = 0.3;
            r = 0.02;
            y_base = 0;

            % Configurar eje
            cla(app.UIAxesSim);
            hold(app.UIAxesSim, 'on');
            axis(app.UIAxesSim, 'equal');
            xlim(app.UIAxesSim, [-0.6 0.6]);
            ylim(app.UIAxesSim, [-0.2 0.4]);
            app.UIAxesSim.XTick = [];
            app.UIAxesSim.YTick = [];

            % Base amarilla
            base_x = [-0.05 0.05 0.05 -0.05];
            base_y = [0 0 0.05 0.05];
            fill(app.UIAxesSim, base_x, base_y, [1 1 0], 'EdgeColor', 'none');

            % Barra verde
            barra_x = [-L/2 L/2];
            barra_y = [y_base y_base];
            plot(app.UIAxesSim, barra_x, barra_y, 'g', 'LineWidth', 6);

            % Bola azul
            theta = linspace(0, 2*pi, 30);
            x_bola = r * cos(theta);
            y_bola = r * sin(theta) + y_base + r;
            fill(app.UIAxesSim, x_bola, y_bola, 'b');

            % Triángulo rojo
            setpoint = 0.2;
            base_tri = 0.03;
            altura_tri = 0.04;
            x_tri = [-base_tri 0 base_tri] + setpoint;
            y_tri = [y_base, y_base + altura_tri, y_base];
            fill(app.UIAxesSim, x_tri, y_tri, 'r');
        end

        % Botón de simulación (PID)
        function SimulateButtonPushed(app)
            pos_deseada = app.InputPosition.Value;
            Kp = app.KpField.Value;
            Ki = app.KiField.Value;
            Kd = app.KdField.Value;

            tiempo_sim = 20;
            Ts = 0.01;
            t = (0:Ts:tiempo_sim)';
            u = pos_deseada * ones(size(t));
            ref_pos = timeseries(u, t);

            assignin('base', 'ref_pos', ref_pos);
            assignin('base', 'Kp', Kp);
            assignin('base', 'Ki', Ki);
            assignin('base', 'Kd', Kd);

            simOut = sim('BALL_BEAM_V4_app');

            pos = simOut.PosicionPelota;
            entrada = simOut.Entrada;
            servo = simOut.angulo_servo;

            % Graficar
            plot(app.UIAxes, pos.time, pos.signals.values, 'b', 'LineWidth', 1.5);
            title(app.UIAxes, 'Posición de la pelota');
            xlabel(app.UIAxes, 'Tiempo [s]');
            ylabel(app.UIAxes, 'Posición [m]');
            grid(app.UIAxes, 'on');

            plot(app.InputAxes, entrada.time, entrada.signals.values, 'r', 'LineWidth', 1.5);
            title(app.InputAxes, 'Entrada PID');
            xlabel(app.InputAxes, 'Tiempo [s]');
            ylabel(app.InputAxes, 'Entrada [rad]');
            grid(app.InputAxes, 'on');

            plot(app.AngleAxes, servo.time, servo.signals.values, 'k', 'LineWidth', 1.5);
            title(app.AngleAxes, 'Ángulo del servo');
            xlabel(app.AngleAxes, 'Tiempo [s]');
            ylabel(app.AngleAxes, 'Ángulo [rad]');
            grid(app.AngleAxes, 'on');
        end

        % Simulación gráfica 2D
        function SimulateButton2Pushed(app)
            L = 0.28;
            r = 0.02;
            y_barra = -0.5;
            setpoint = app.InputSetpoint.Value;
            setpoint = max(min(setpoint, L/2 - r), -L/2 + r);

            cla(app.UIAxesSim);
            axis(app.UIAxesSim, [-0.6 0.6 -0.2 0.4]);
            axis(app.UIAxesSim, 'equal');
            hold(app.UIAxesSim, 'on');

            x_barra = [-L/2, L/2];
            y_barra_arriba = [y_barra y_barra];
            plot(app.UIAxesSim, x_barra, y_barra_arriba, 'g', 'LineWidth', 6);

            base = 0.03;
            altura = 0.04;
            x_tri = [-base 0 base] + setpoint;
            y_tri = [y_barra, y_barra + altura, y_barra];
            fill(app.UIAxesSim, x_tri, y_tri, 'r');

            x_actual = -L/4;
            x_target = setpoint;
            pasos = 100;

            for i = 1:pasos
                x_bola = x_actual + (x_target - x_actual) * i/pasos;
                angulo = 15 * sin(pi * i/pasos);

                theta = linspace(0, 2*pi, 30);
                x_circle = r * cos(theta) + x_bola;
                y_circle = r * sin(theta) + y_barra + r;
                h_bola = fill(app.UIAxesSim, x_circle, y_circle, 'b');

                pause(0.01);
                if i < pasos
                    delete(h_bola);
                end
            end

            hold(app.UIAxesSim, 'off');
        end
    end

    % App creation and deletion
    methods (Access = public)

        function app = Ball_beam_V4
            createComponents(app)
            registerApp(app, app.UIFigure)
            startupFcn(app);  % <- Dibujo 2D al abrir
            if nargout == 0
                clear app
            end
        end

        function delete(app)
            delete(app.UIFigure)
        end
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
           
        end

        % Callback function: not associated with a component
        function RunSimButtonPushed(app, event)
          
        end

        % Button pushed function: SimulateButton2
        function SimulateButton2Pushed(app, event)
            % Parámetros del sistema
            L = 0.28;                  % Longitud de la barra (1 metro)
            r = 0.02;               % Radio de la bola
            y_barra = -0.5;            % Altura de la barra
            setpoint = app.InputSetpoint.Value; % Entrada del usuario
        
            % Validación de límites
            setpoint = max(min(setpoint, L/2 - r), -L/2 + r);
        
            % Crear el eje
            cla(app.UIAxesSim);
            axis(app.UIAxesSim, [-0.6 0.6 -0.2 0.4]);
            axis(app.UIAxesSim, 'equal');
            hold(app.UIAxesSim, 'on');
        
            % Dibujar la barra
            x_barra = [-L/2, L/2];
            y_barra_arriba = [y_barra y_barra];
            plot(app.UIAxesSim, x_barra, y_barra_arriba, 'g', 'LineWidth', 6);
        
            % Dibujar el triángulo del setpoint
            base = 0.03;
            altura = 0.04;
            x_tri = [-base 0 base] + setpoint;
            y_tri = [y_barra, y_barra + altura, y_barra];
            fill(app.UIAxesSim, x_tri, y_tri, 'r');
        
            % Simular el movimiento de la pelota hasta el setpoint
            x_actual = -L/4;  % posición inicial
            x_target = setpoint;
            pasos = 100;
        
            for i = 1:pasos
                x_bola = x_actual + (x_target - x_actual) * i/pasos;
                angulo = 15 * sin(pi * i/pasos);  % animación con inclinación
        
                % Dibujar la bola
                theta = linspace(0, 2*pi, 30);
                x_circle = r * cos(theta) + x_bola;
                y_circle = r * sin(theta) + y_barra + r;
                h_bola = fill(app.UIAxesSim, x_circle, y_circle, 'b');
        
                % Pausa para animación
                pause(0.01);
        
                if i < pasos
                    delete(h_bola);  % Borrar bola anterior
                end
            end
        
            hold(app.UIAxesSim, 'off');

        end

        % Button down function: UIAxesSim
        function startupFcn2(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AngleAxes = uiaxes(app.UIFigure);
            title(app.AngleAxes, 'Title')
            xlabel(app.AngleAxes, 'X')
            ylabel(app.AngleAxes, 'Y')
            zlabel(app.AngleAxes, 'Z')
            app.InputAxes = uiaxes(app.UIFigure);
            title(app.InputAxes, 'Title')
            xlabel(app.InputAxes, 'X')
            ylabel(app.InputAxes, 'Y')
            zlabel(app.InputAxes, 'Z')
            app.UIAxesSim = uiaxes(app.UIFigure);
            title(app.UIAxesSim, 'Title')
            xlabel(app.UIAxesSim, 'X')
            ylabel(app.UIAxesSim, 'Y')
            zlabel(app.UIAxesSim, 'Z')
            app.UIAxesSim.XLim = [-0.3 0.3];
            app.UIAxesSim.YLim = [0 2];
            app.UIAxesSim.ButtonDownFcn = createCallbackFcn(app, @startupFcn2, true);
            app.InputPositionEditFieldLabel = uilabel(app.UIFigure);
            app.InputPositionEditFieldLabel.Text = 'InputPosition';
            app.InputPosition = uieditfield(app.UIFigure, 'numeric');
            app.InputPosition.Limits = [0.02 0.045];
            app.InputPosition.Value = 0.035;
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simular';
            app.KPEditFieldLabel = uilabel(app.UIFigure);
            app.KPEditFieldLabel.Text = 'KP';
            app.KpField = uieditfield(app.UIFigure, 'numeric');
            app.KpField.Limits = [85 230];
            app.KpField.Value = 110;
            app.KILabel = uilabel(app.UIFigure);
            app.KILabel.Text = 'KI';
            app.KiField = uieditfield(app.UIFigure, 'numeric');
            app.KiField.Limits = [0.1 0.5];
            app.KiField.Value = 0.32;
            app.KDLabel = uilabel(app.UIFigure);
            app.KDLabel.Text = 'KD';
            app.KdField = uieditfield(app.UIFigure, 'numeric');
            app.KdField.Limits = [0.1 1];
            app.KdField.Value = 0.51;
            app.SimulateButton2 = uibutton(app.UIFigure, 'push');
            app.SimulateButton2.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton2Pushed, true);
            app.SimulateButton2.Text = 'simu2';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.InputSetpoint = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ball_beam_V4

            % Associate the Simulink Model
            app.Simulation = simulation('BALL_BEAM_V4_app');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end