classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        KiLabel         matlab.ui.control.Label
        KdLabel         matlab.ui.control.Label
        KpLabel         matlab.ui.control.Label
        AngleKnobLabel  matlab.ui.control.Label
        AngleKnob       matlab.ui.control.Knob
        SimulinkButton  matlab.ui.control.Button
        RESETButton     matlab.ui.control.Button
        StopButton      matlab.ui.control.Button
        KiKnob          matlab.ui.control.Knob
        KdKnob          matlab.ui.control.Knob
        RunButton       matlab.ui.control.Button
        ModeDropdown    matlab.ui.control.DropDown
        DropDownLabel   matlab.ui.control.Label
        KpKnob          matlab.ui.control.Knob
        SetpointSlider  matlab.ui.control.Slider
        BALLPOSLabel    matlab.ui.control.Label
        ResponseAxes    matlab.ui.control.UIAxes
        BallBeamAxes    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        responseLine              % animatedline para posición bola
        setpointLine 
        ball           % Handle de la bola
        beam           % Handle de la barra
        marker         % Handle del setpoint (triángulo rojo)
        support       % Handle del soporte (triángulo verde lima)
        
        x              % Posición de la bola
        v              % Velocidad
        u              % Ángulo de la barra
        r              % Radio de la bola
        L              % Longitud de la barra
        g              % Gravedad
        m  % Massa
        done           % Flag de simulación activa
        e_prev          % Error anterior
        e_int           % Integral del error
    end
    
    methods (Access = private)
        
        function initBallBeam(app)

            % Bloquear interacciones (zoom, pan, rotate)
            disableDefaultInteractivity(app.BallBeamAxes);
            
            % Fijar límites de los ejes y desactivar autoajuste
            app.BallBeamAxes.XLim = [-0.24 0.24];
            app.BallBeamAxes.YLim = [-0.15 0.15];
            app.BallBeamAxes.XLimMode = 'manual';
            app.BallBeamAxes.YLimMode = 'manual';

            % Parámetros físicos
            app.r = 0.02;     % radio de la bola
            app.L = 0.15;      % longitud de la barra
            app.g = 9.81;     % gravedad
        
            % Estado inicial
            app.x = 0;
            app.v = 0;
            app.u = 0;
            app.done = false;
        
            % Preparar el eje
            hold(app.BallBeamAxes, 'on');
            axis(app.BallBeamAxes, [-0.20 0.20 -0.15 0.15]);
            axis(app.BallBeamAxes, 'equal');
            cla(app.BallBeamAxes); % Limpiar por si acaso
        
            % Dibujar barra
            beamX = [-app.L app.L];
            beamY = [0 0];
            app.beam = plot(app.BallBeamAxes, beamX, beamY, 'g-', 'LineWidth', 6);
        
            % Dibujar bola (círculo)
            theta = linspace(0, 2*pi, 30);
            xb = [0 app.r * cos(theta)];
            yb = [0 app.r * sin(theta)];
            app.ball = fill(app.BallBeamAxes, xb + 0, yb + 0.02, 'b');
        
            % Dibujar setpoint (triángulo rojo)
            sp = app.SetpointSlider.Value;
            triX = [0 -app.r app.r];
            triY = [0 -app.r -app.r];
            app.marker = fill(app.BallBeamAxes, triX + sp, triY - 0.00026, 'r');

            % Dibuixar punt de suport (triangle)
            baseX = [0.02 0 -0.02];                 
            baseY = [-0.1 -0.00026 -0.1];                      
            fill(app.BallBeamAxes, baseX - 0.15, baseY, [207, 250, 62]/255,'EdgeColor', 'none', 'LineStyle', 'none');

            app.BallBeamAxes.XColor = 'none';
            app.BallBeamAxes.YColor = 'none';
            app.BallBeamAxes.XLabel.String = '';
            app.BallBeamAxes.YLabel.String = '';
            app.BallBeamAxes.Title.String = '';
        end
        
        
        function updateBallBeam(app, advance)
            if nargin < 2
                advance = true;  % Por defecto, avanza la simulación
            end
        
            dt = 0.01;

            if advance
                a = -app.g * sin(app.u);
                app.v = app.v + a * dt;
                app.x = app.x + app.v * dt;
            end

            % Punto de rotación (pivote del servo)
            pivot = [-0.15; -0.00026];
        
            % Matriz de rotación
            R = [cos(app.u), -sin(app.u); 
                 sin(app.u),  cos(app.u)];
        
            %% === Actualizar barra verde ===
            beamPoints = [-app.L, app.L; 0, 0];  % Extremos de la barra
            beam_rot = R * (beamPoints - pivot) + pivot;
            app.beam.XData = beam_rot(1, :);
            app.beam.YData = beam_rot(2, :);
        
            %% === Actualizar bola ===
            theta = linspace(0, 2*pi, 30);
            xb = app.r * cos(theta);
            yb = app.r * sin(theta);
        
            pos_bola_local = [app.x; 0];  % En coordenadas locales
            bola_rotada = R * (pos_bola_local - pivot) + pivot;
            bola_rotada(2) = bola_rotada(2) + app.r;  % Elevar para apoyar por la base
        
            app.ball.XData = xb + bola_rotada(1);
            app.ball.YData = yb + bola_rotada(2);
        
            %% === Actualizar marcador de setpoint (triángulo rojo) ===
            % Triángulo como forma (coordenadas relativas)
            triX = [0 -app.r app.r];
            triY = [0 -app.r -app.r];
            
            % Posición en coordenadas locales del triángulo
            sp = app.SetpointSlider.Value;
            tri_local = [triX; triY];
            
            % Aplicar rotación y traslación del punto central (como con la bola)
            tri_rotated = R * (tri_local + [sp; 0] - pivot) + pivot;
            
            % Asignar coordenadas rotadas
            app.marker.XData = tri_rotated(1, :);
            app.marker.YData = tri_rotated(2, :);

            % Soporte (triángulo verde lima fijo)
            baseX = [0.02 0 -0.02];
            baseY = [-0.1 -0.00026 -0.1];
            app.support = fill(app.BallBeamAxes, baseX - 0.15, baseY,[207, 250, 62]/255, 'EdgeColor', 'none', 'LineStyle', 'none');
           
            app.support.XData = baseX - 0.15;
            app.support.YData = baseY;

            app.BallBeamAxes.XLim = [-0.24 0.24];
            app.BallBeamAxes.YLim = [-0.15 0.15];
            app.BallBeamAxes.XLimMode = 'manual';
            app.BallBeamAxes.YLimMode = 'manual';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initBallBeam(app);
            app.e_prev = 0;
            app.e_int = 0;
            cla(app.ResponseAxes);
            app.responseLine = animatedline(app.ResponseAxes, 'Color', 'b');
            app.setpointLine = animatedline(app.ResponseAxes, 'Color', 'r', 'LineStyle', '--');
            app.ResponseAxes.YLim = [-0.17 0.17];
            app.ResponseAxes.YLimMode = 'manual';
            app.ResponseAxes.XLabel.String = 'Tiempo (s)';
            app.ResponseAxes.YLabel.String = 'Posición de la bola (m)';
        end

        % Value changed function: SetpointSlider
        function SetpointSliderValueChanged(app, event)
            % Obtener el nuevo valor del slider (posición a lo largo de la barra)
            sp = app.SetpointSlider.Value;
        
            % Coordenadas del triángulo en su sistema local
            triX = [0 -app.r app.r];
            triY = [0 -app.r -app.r];
            tri_local = [triX; triY];
        
            % Punto de pivote
            pivot = [-0.15; -0.00026];
        
            % Matriz de rotación según el ángulo actual de la barra
            R = [cos(app.u), -sin(app.u); 
                 sin(app.u),  cos(app.u)];
        
            % Aplicar rotación a la posición del triángulo más su forma
            tri_rotated = R * (tri_local + [sp; 0] - pivot) + pivot;
        
            % Actualizar posición del triángulo
            app.marker.XData = tri_rotated(1, :);
            app.marker.YData = tri_rotated(2, :);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.done = false;
            app.e_prev = 0;
            app.e_int = 0;
            app.m = 0.02;
            g = -9.81;
            r = 0.028;
            L = 0.30;
            R = 0.02;
            Iball = (2/5) * app.m * R^2;
        
            dt = 0.01;
            t = 0;
            app.x = 0;
            app.v = 0;
        
            clearpoints(app.responseLine);
            clearpoints(app.setpointLine);
            
            setpoint = app.SetpointSlider.Value;
            contadorEstable = 0;
            umbral = 0.002;
        
            while ~app.done
                drawnow;  % permite responder a STOP
                if app.done
                    break;
                end
        
                % Error de posición
                e = -setpoint + app.x
        
                % Leer modo y aplicar PID si corresponde
                modo = app.ModeDropdown.Value;
                if strcmp(modo, 'PID')
                    Kp = app.KpKnob.Value;
                    Ki = app.KiKnob.Value;
                    Kd = app.KdKnob.Value;
        
                    P = Kp * e;
                    app.e_int = app.e_int + e * dt;
                    app.e_int = min(max(app.e_int, -0.33), 0.33);
                    I = Ki * app.e_int;
                    D = Kd * (e - app.e_prev) / dt;
                    u_pid = (P + I + D);
        
                    % Límite físico del servo
                    servo_angle = max(min(u_pid, 6), -6);
                    app.u = deg2rad(servo_angle);  % radianes
                end
        
                % ⚠️ Dinámica realista basada en función de transferencia física
                den = app.m + Iball / (R^2);
                K = (app.m * g * R) / (L * den);
                a = K * app.u;
        
                % Integración simple
                app.v = app.v + a * dt;
                app.x = app.x + app.v * dt;
                app.x = max(min(app.x, 0.15), -0.15);  % límites físicos
        
                % Actualizar animación
                updateBallBeam(app, false);
        
                % Actualizar gráfico interactivo
                t = t + dt;
                addpoints(app.responseLine, t, app.x);
                addpoints(app.setpointLine, t, setpoint);
                app.ResponseAxes.XLim = [max(0, t-30) t];
                drawnow limitrate;
        
                % Condición de parada automática
                if abs(e) < umbral
                    contadorEstable = contadorEstable + 1;
                else
                    contadorEstable = 0;
                end
                if contadorEstable >= 30
                    app.done = true;
                end
        
                app.e_prev = e;
                % pause(0);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.done = true; % Termina la simulación
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.x = 0;
            app.v = 0;
            app.u = 0;
            app.AngleKnob.Value = 0;
            app.SetpointSlider.Value = 0;
            clearpoints(app.responseLine);
            clearpoints(app.setpointLine);
            
            updateBallBeam(app);
        end

        % Value changed function: AngleKnob
        function AngleKnobValueChanged(app, event)
            app.u = deg2rad(app.AngleKnob.Value);

            % Actualizar visualmente el sistema sin avanzar la simulación
            updateBallBeam(app, false);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BallBeamAxes = uiaxes(app.UIFigure);
            title(app.BallBeamAxes, 'Title')
            xlabel(app.BallBeamAxes, 'X')
            ylabel(app.BallBeamAxes, 'Y')
            zlabel(app.BallBeamAxes, 'Z')
            app.ResponseAxes = uiaxes(app.UIFigure);
            title(app.ResponseAxes, 'Ball Position vs Setpoint')
            xlabel(app.ResponseAxes, 'Time (s)')
            ylabel(app.ResponseAxes, 'Position (m)''')
            zlabel(app.ResponseAxes, 'Z')
            app.BALLPOSLabel = uilabel(app.UIFigure);
            app.BALLPOSLabel.Text = 'BALL POS';
            app.SetpointSlider = uislider(app.UIFigure);
            app.SetpointSlider.Limits = [-0.15 0.15];
            app.SetpointSlider.ValueChangedFcn = createCallbackFcn(app, @SetpointSliderValueChanged, true);
            app.KpKnob = uiknob(app.UIFigure, 'continuous');
            app.KpKnob.Limits = [110 125];
            app.KpKnob.Value = 114;
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.ModeDropdown = uidropdown(app.UIFigure);
            app.ModeDropdown.Items = {'Manual', 'P', 'PD', 'PID'};
            app.ModeDropdown.Value = 'Manual';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'RUN';
            app.KdKnob = uiknob(app.UIFigure, 'continuous');
            app.KdKnob.Limits = [0 0.1];
            app.KdKnob.Value = 0.051;
            app.KiKnob = uiknob(app.UIFigure, 'continuous');
            app.KiKnob.Limits = [1 5];
            app.KiKnob.Value = 3.2;
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'STOP';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.SimulinkButton = uibutton(app.UIFigure, 'push');
            app.SimulinkButton.Text = 'Model Simulink';
            app.AngleKnob = uiknob(app.UIFigure, 'continuous');
            app.AngleKnob.Limits = [-6 6];
            app.AngleKnob.ValueChangedFcn = createCallbackFcn(app, @AngleKnobValueChanged, true);
            app.AngleKnobLabel = uilabel(app.UIFigure);
            app.AngleKnobLabel.Text = 'Angle ';
            app.KpLabel = uilabel(app.UIFigure);
            app.KpLabel.Text = 'Kp';
            app.KdLabel = uilabel(app.UIFigure);
            app.KdLabel.Text = 'Ki';
            app.KiLabel = uilabel(app.UIFigure);
            app.KiLabel.Text = 'Kd';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end